classdef load_GMMNLSE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GMMNLSE_propagateinputparametersLabel  matlab.ui.control.Label
        CenterfrequencyTHzEditFieldLabel  matlab.ui.control.Label
        CenterfrequencyTHzEditField     matlab.ui.control.NumericEditField
        CenterwavelengthnmEditFieldLabel  matlab.ui.control.Label
        CenterwavelengthnmEditField     matlab.ui.control.NumericEditField
        TabGroup2                       matlab.ui.container.TabGroup
        GainmodelTab                    matlab.ui.container.Tab
        GaindBmEditFieldLabel           matlab.ui.control.Label
        GaindBmEditField                matlab.ui.control.NumericEditField
        gaincoeff1mEditFieldLabel       matlab.ui.control.Label
        gaincoeff1mEditField            matlab.ui.control.NumericEditField
        FWHMnmEditFieldLabel            matlab.ui.control.Label
        FWHMnmEditField                 matlab.ui.control.NumericEditField
        TabGroup3                       matlab.ui.container.TabGroup
        EnergyTab                       matlab.ui.container.Tab
        SaturationenergynJEditFieldLabel  matlab.ui.control.Label
        SaturationenergynJEditField     matlab.ui.control.NumericEditField
        IntensityTab                    matlab.ui.container.Tab
        SaturationintensityJm2EditFieldLabel  matlab.ui.control.Label
        SaturationintensityJm2EditField  matlab.ui.control.NumericEditField
        DerivationfromscratchTab        matlab.ui.container.Tab
        UpperstatelifetimesEditFieldLabel  matlab.ui.control.Label
        UpperstatelifetimesEditField    matlab.ui.control.NumericEditField
        LaserrepetitionrateHzEditFieldLabel  matlab.ui.control.Label
        LaserrepetitionrateHzEditField  matlab.ui.control.NumericEditField
        Totalcrosssectionm2emissionabsorptionEditFieldLabel  matlab.ui.control.Label
        Totalcrosssectionm2emissionabsorptionEditField  matlab.ui.control.NumericEditField
        MPATab                          matlab.ui.container.Tab
        NumberofparallelizationsEditFieldLabel  matlab.ui.control.Label
        NumberofparallelizationsEditField  matlab.ui.control.NumericEditField
        MaxiterationsEditFieldLabel     matlab.ui.control.Label
        MaxiterationsEditField          matlab.ui.control.NumericEditField
        MiniterationsEditFieldLabel     matlab.ui.control.Label
        MiniterationsEditField          matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.NumericEditField
        AdaptivealgorithmTab            matlab.ui.container.Tab
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        maxdeltaZEditFieldLabel         matlab.ui.control.Label
        maxdeltaZEditField              matlab.ui.control.NumericEditField
        RamanTab                        matlab.ui.container.Tab
        IncludespontaneousRamanButton   matlab.ui.control.StateButton
        GPUTab                          matlab.ui.container.Tab
        GPUindextouseEditFieldLabel     matlab.ui.control.Label
        GPUindextouseEditField          matlab.ui.control.NumericEditField
        CudafolderEditFieldLabel        matlab.ui.control.Label
        CudafolderEditField             matlab.ui.control.EditField
        ProgressbarTab                  matlab.ui.container.Tab
        ProgressbarnameEditFieldLabel   matlab.ui.control.Label
        ProgressbarnameEditField        matlab.ui.control.EditField
        SimulationtypeButtonGroup       matlab.ui.container.ButtonGroup
        singlemodeButton                matlab.ui.control.RadioButton
        multimodeButton                 matlab.ui.control.RadioButton
        IncludeshocktermButton          matlab.ui.control.StateButton
        StepsizemEditFieldLabel         matlab.ui.control.Label
        StepsizemEditField              matlab.ui.control.NumericEditField
        SavingstepsizemEditFieldLabel   matlab.ui.control.Label
        SavingstepsizemEditField        matlab.ui.control.NumericEditField
        ScalarPolarizedsimulationsButtonGroup  matlab.ui.container.ButtonGroup
        scalarButton                    matlab.ui.control.RadioButton
        polarizedButton                 matlab.ui.control.RadioButton
        EllipticityEditFieldLabel       matlab.ui.control.Label
        EllipticityEditField            matlab.ui.control.NumericEditField
        AdaptivealgorithmButtonGroup    matlab.ui.container.ButtonGroup
        adaptivestepsizeButton          matlab.ui.control.RadioButton
        fixedstepsizeButton             matlab.ui.control.RadioButton
        NumericalprecisionButtonGroup   matlab.ui.container.ButtonGroup
        singleButton                    matlab.ui.control.RadioButton
        doubleButton                    matlab.ui.control.RadioButton
        RamanmodelDropDownLabel         matlab.ui.control.Label
        RamanmodelDropDown              matlab.ui.control.DropDown
        SteppingalgorithmDropDownLabel  matlab.ui.control.Label
        SteppingalgorithmDropDown       matlab.ui.control.DropDown
        UseGPUButton                    matlab.ui.control.StateButton
        CenterthepulsewrtthetimewindowButton  matlab.ui.control.StateButton
        PhotonnoiseperfreqbandLabel     matlab.ui.control.Label
        PhotonnoiseperfreqbandEditField  matlab.ui.control.NumericEditField
        GainmodelDropDownLabel          matlab.ui.control.Label
        GainmodelDropDown               matlab.ui.control.DropDown
        FiberPanel                      matlab.ui.container.Panel
        FiberlengthmEditFieldLabel      matlab.ui.control.Label
        FiberlengthmEditField           matlab.ui.control.NumericEditField
        Nonlinearrefractiveindexn2m2WEditFieldLabel  matlab.ui.control.Label
        Nonlinearrefractiveindexn2m2WEditField  matlab.ui.control.NumericEditField
        FibermaterialDropDownLabel      matlab.ui.control.Label
        FibermaterialDropDown           matlab.ui.control.DropDown
        TabGroup                        matlab.ui.container.TabGroup
        SinglemodeTab                   matlab.ui.container.Tab
        MFDumEditField_2Label           matlab.ui.control.Label
        MFDumEditField_2                matlab.ui.control.NumericEditField
        BetasButtonGroup                matlab.ui.container.ButtonGroup
        Beta0Button                     matlab.ui.control.ToggleButton
        Beta1Button                     matlab.ui.control.ToggleButton
        Beta2Button                     matlab.ui.control.ToggleButton
        Beta3Button                     matlab.ui.control.ToggleButton
        Beta4Button                     matlab.ui.control.ToggleButton
        mLabel                          matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        MultimodeTab                    matlab.ui.container.Tab
        modeindexEditFieldLabel         matlab.ui.control.Label
        modeindexEditField              matlab.ui.control.EditField
        MM_folderButton                 matlab.ui.control.Button
        BetasfilenameEditFieldLabel     matlab.ui.control.Label
        BetasfilenameEditField          matlab.ui.control.EditField
        StensorsfilenameEditFieldLabel  matlab.ui.control.Label
        StensorsfilenameEditField       matlab.ui.control.EditField
        ShowprogressbarButton           matlab.ui.control.StateButton
        TimewindowBetasButtonGroup      matlab.ui.container.ButtonGroup
        Beta0Button_2                   matlab.ui.control.ToggleButton
        Beta1Button_2                   matlab.ui.control.ToggleButton
        mLabel_2                        matlab.ui.control.Label
        ValueEditFieldLabel             matlab.ui.control.Label
        ValueEditField                  matlab.ui.control.NumericEditField
        RmcmodelDropDownLabel           matlab.ui.control.Label
        RmcmodelDropDown                matlab.ui.control.DropDown
        ClosethisforexportingtheparametersLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        c = 299792458; % m/s; speed of light
        upper_folder = [];
        betas = []; % Taylor-expansion coefficients of the propagation constant
        Aeff = []; % m^2; effective area of the SMF
        SR = []; % S tensors
        
        MM_folder = '';
        midx = 1; % mode index
        
        sim_betas = [];
    end
    
    methods (Access = private)
        
        function read_betas(app)
            % Find the default betas
            
            switch app.SimulationtypeButtonGroup.SelectedObject.Text
                case 'single mode'
                    DefaultButtonPushed(app);
                case 'multimode'
                    try
                        % Load betas from the specified file
                        loaded_variables = load(fullfile(app.MM_folder,app.BetasfilenameEditField.Value),'betas'); % in fs^n/mm
                        if max(app.midx) > max(size(loaded_variables.betas,2))
                            ME = MException('read_betas:midxWrongValue',sprintf('Mode index can''t be larger than the mode number in "%s".',app.BetasfilenameEditField.Value));
                            throw(ME);
                        end
                        app.betas = loaded_variables.betas;
                        
                        unit_conversion = 0.001.^(-1:size(app.betas, 1)-2)'; % The imported values are in fs^n/mm, but the simulation uses ps^n/m
                        app.betas = app.betas(:,app.midx).*unit_conversion;
                    catch ME
                        switch ME.identifier
                            case 'read_betas:midxWrongValue'
                                errordlg(ME.message,'Load Betas Error','replace');
                            case 'MATLAB:load:couldNotReadFile'
                                errordlg(sprintf('Please check if MM_folder contains "betas" variable in the "%s".',app.BetasfilenameEditField.Value),'Load Betas Error','replace');
                        end
                    end
            end
            
            % Update time-window betas
            app.sim_betas = app.betas([1,2],1);
            switch app.TimewindowBetasButtonGroup.SelectedObject.Text
                case 'Beta0'
                    app.ValueEditField.Value = app.sim_betas(1);
                case 'Beta1'
                    app.ValueEditField.Value = app.sim_betas(2);
            end
        end
        function read_SR(app)
            switch app.SimulationtypeButtonGroup.SelectedObject.Text
                case 'single mode'
                    MFDumEditField_2ValueChanged(app);
                case 'multimode'
                    try
                        % Load SR from the specified file
                        loaded_variables = load(fullfile(app.MM_folder,app.StensorsfilenameEditField.Value),'SR'); % in 1/m^2
                        if max(app.midx) > max(size(loaded_variables.SR))
                            ME = MException('read_SR:midxWrongValue',sprintf('Mode index can''t be larger than the mode number in "%s".',app.StensorsfilenameEditField.Value));
                            throw(ME);
                        end
                        app.SR = loaded_variables.SR(app.midx,app.midx,app.midx,app.midx);
                        
                        app.Aeff = 1/app.SR(1,1,1,1); % m^2
                        
                        % Update gain values
                        UpperstatelifetimesEditFieldValueChanged(app);
                        LaserrepetitionrateHzEditFieldValueChanged(app);
                        Totalcrosssectionm2emissionabsorptionEditFieldValueChanged(app);
                    catch ME
                        switch ME.identifier
                            case 'read_SR:midxWrongValue'
                                errordlg(ME.message,'Load SR Error','replace');
                            case 'MATLAB:load:couldNotReadFile'
                                errordlg(sprintf('Please check if MM_folder contains "SR" variable in the "%s".',app.StensorsfilenameEditField.Value),'Load SR Error','replace');
                        end
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ispc
                sep = '\';
            else % unix
                sep = '/';
            end
            current_path = mfilename('fullpath');
            sep_pos = strfind(current_path,sep);
            app.upper_folder = current_path(1:sep_pos(end));
            
            read_betas(app);
            read_SR(app);
            
            UseGPUButtonValueChanged(app);
            maxdeltaZEditFieldValueChanged(app);
        end

        % Value changed function: CenterfrequencyTHzEditField
        function CenterfrequencyTHzEditFieldValueChanged(app, event)
            app.CenterwavelengthnmEditField.Value = app.c/app.CenterfrequencyTHzEditField.Value*1e-3; % nm
        end

        % Value changed function: CenterwavelengthnmEditField
        function CenterwavelengthnmEditFieldValueChanged(app, event)
            app.CenterfrequencyTHzEditField.Value = app.c/app.CenterwavelengthnmEditField.Value*1e-3; % THz
        end

        % Selection changed function: SimulationtypeButtonGroup
        function SimulationtypeButtonGroupSelectionChanged(app, event)
            switch app.SimulationtypeButtonGroup.SelectedObject.Text
                case 'single mode'
                    % Enable
                    app.MFDumEditField_2Label.Enable = 'on';
                    app.MFDumEditField_2.Enable = 'on';
                    app.BetasButtonGroup.Enable = 'on';
                    matlab_year = version('-release'); % ButtonGroup has the "Enable" property only after version 2019.
                    if str2double(matlab_year(1:4)) > 2019
                        app.BetasButtonGroup.Enable = 'on';
                    else
                        app.XEditFieldLabel.Enable = 'on';
                        app.XEditField.Enable = 'on';
                        app.mLabel.Enable = 'on';
                        app.DefaultButton.Enable = 'on';
                    end
                    % Disable
                    app.modeindexEditFieldLabel.Enable = 'off';
                    app.modeindexEditField.Enable = 'off';
                    app.MM_folderButton.Enable = 'off';
                    app.BetasfilenameEditFieldLabel.Enable = 'off';
                    app.BetasfilenameEditField.Enable = 'off';
                    app.StensorsfilenameEditFieldLabel.Enable = 'off';
                    app.StensorsfilenameEditField.Enable = 'off';
                case 'multimode'
                    % Enable
                    app.modeindexEditFieldLabel.Enable = 'on';
                    app.modeindexEditField.Enable = 'on';
                    app.MM_folderButton.Enable = 'on';
                    app.BetasfilenameEditFieldLabel.Enable = 'on';
                    app.BetasfilenameEditField.Enable = 'on';
                    app.StensorsfilenameEditFieldLabel.Enable = 'on';
                    app.StensorsfilenameEditField.Enable = 'on';
                    % Disable
                    app.MFDumEditField_2Label.Enable = 'off';
                    app.MFDumEditField_2.Enable = 'off';
                    matlab_year = version('-release'); % ButtonGroup has the "Enable" property only after version 2019.
                    if str2double(matlab_year(1:4)) > 2019
                        app.BetasButtonGroup.Enable = 'off';
                    else
                        app.XEditFieldLabel.Enable = 'off';
                        app.XEditField.Enable = 'off';
                        app.mLabel.Enable = 'off';
                        app.DefaultButton.Enable = 'off';
                    end
            end
        end

        % Selection changed function: BetasButtonGroup
        function BetasButtonGroupSelectionChanged(app, event)
            switch app.BetasButtonGroup.SelectedObject.Text
                case 'Beta0'
                    app.XEditField.Value = app.betas(1,1);
                    app.YEditField.Value = app.betas(1,2);
                    app.mLabel.Text = '1/m';
                    app.mLabel_3.Text = '1/m';
                case 'Beta1'
                    app.XEditField.Value = app.betas(2,1);
                    app.YEditField.Value = app.betas(2,2);
                    app.mLabel.Text = 'ps/m';
                    app.mLabel_3.Text = 'ps/m';
                case 'Beta2'
                    app.XEditField.Value = app.betas(3,1);
                    app.YEditField.Value = app.betas(3,2);
                    app.mLabel.Text = 'ps^2/m';
                    app.mLabel_3.Text = 'ps^2/m';
                case 'Beta3'
                    app.XEditField.Value = app.betas(4,1);
                    app.YEditField.Value = app.betas(4,2);
                    app.mLabel.Text = 'ps^3/m';
                    app.mLabel_3.Text = 'ps^3/m';
                case 'Beta4'
                    app.XEditField.Value = app.betas(5,1);
                    app.YEditField.Value = app.betas(5,2);
                    app.mLabel.Text = 'ps^4/m';
                    app.mLabel_3.Text = 'ps^4/m';
            end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            if app.CenterwavelengthnmEditField.Value < 1300 % lambda(zero dispersion)=1.3um; assume 1030nm for positive dispersion
                app.betas = repmat([8.8268e6; 4.8821e3; 0.0209; 32.9e-6; -26.7e-9],1,2);
                app.MFDumEditField_2.Value = 5.95; % um; 1030nm from Thorlabs 1060XP
            else % assume 1550nm for anomalous dispersion
                app.betas = repmat([5.8339e6; 4.8775e3; -0.0123; 0.1049e-6; -378.3e-9],1,2);
                app.MFDumEditField_2.Value = 8.09; % um; 1550nm from Thorlabs 1060XP
            end
            
            % Update
            switch app.BetasButtonGroup.SelectedObject.Text
                case 'Beta0'
                    app.XEditField.Value = app.betas(1,1);
                    app.YEditField.Value = app.betas(1,2);
                case 'Beta1'
                    app.XEditField.Value = app.betas(2,1);
                    app.YEditField.Value = app.betas(2,2);
                case 'Beta2'
                    app.XEditField.Value = app.betas(3,1);
                    app.YEditField.Value = app.betas(3,2);
                case 'Beta3'
                    app.XEditField.Value = app.betas(4,1);
                    app.YEditField.Value = app.betas(4,2);
                case 'Beta4'
                    app.XEditField.Value = app.betas(5,1);
                    app.YEditField.Value = app.betas(5,2);
            end
            
            % Update time-window betas
            app.sim_betas = app.betas([1,2],1);
            switch app.TimewindowBetasButtonGroup.SelectedObject.Text
                case 'Beta0'
                    app.ValueEditField.Value = app.sim_betas(1);
                case 'Beta1'
                    app.ValueEditField.Value = app.sim_betas(2);
            end
        end

        % Value changed function: MFDumEditField_2
        function MFDumEditField_2ValueChanged(app, event)
            app.Aeff = pi*(app.MFDumEditField_2.Value/2)^2*1e-12; % m^2; effective area of the SMF
            app.SR = 1/app.Aeff;
        end

        % Button pushed function: MM_folderButton
        function MM_folderButtonPushed(app, event)
            MM_folder_string = uigetdir(app.upper_folder,'Multimode parameter folder');
            figure(app.UIFigure);
            
            if MM_folder_string == 0
                errordlg('Please specify the folder where betas and SR are saved.','MM_folder Error','replace');
            else
                app.MM_folder = MM_folder_string;
                
                % Load betas and SR from files
                read_betas(app);
                read_SR(app);
            end
        end

        % Value changed function: modeindexEditField
        function modeindexEditFieldValueChanged(app, event)
            try
                midx_string = unique(uint8(eval(['[',app.modeindexEditField.Value,']'])));
                if size(midx_string,1) ~= 1
                    ME = MException('modeindexEditFieldValueChanged:midxWrongSize','');
                    throw(ME);
                end
                if any(midx_string == 0)
                    ME = MException('modeindexEditFieldValueChanged:midxWrongValue','');
                    throw(ME);
                end
                
                app.midx = midx_string;
                
            catch
                errordlg('mode index needs to be a 1-by-n integer array.','midx Error','replace');
            end
            
            % If there's no error,
            % I do this again in case the input contains decimals for which uint8() removes the decimal parts.
            % If there's an error,
            % put the original midx back
            app.modeindexEditField.Value = num2str(app.midx);
            
            % Load betas and SR from files
            read_betas(app);
            read_SR(app);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            switch app.BetasButtonGroup.SelectedObject.Text
                case 'Beta0'
                    app.betas(1,1) = app.XEditField.Value;
                case 'Beta1'
                    app.betas(2,1) = app.XEditField.Value;
                case 'Beta2'
                    app.betas(3,1) = app.XEditField.Value;
                case 'Beta3'
                    app.betas(4,1) = app.XEditField.Value;
                case 'Beta4'
                    app.betas(5,1) = app.XEditField.Value;
            end
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            switch app.BetasButtonGroup.SelectedObject.Text
                case 'Beta0'
                    app.betas(1,2) = app.YEditField.Value;
                case 'Beta1'
                    app.betas(2,2) = app.YEditField.Value;
                case 'Beta2'
                    app.betas(3,2) = app.YEditField.Value;
                case 'Beta3'
                    app.betas(4,2) = app.YEditField.Value;
                case 'Beta4'
                    app.betas(5,2) = app.YEditField.Value;
            end
        end

        % Value changed function: BetasfilenameEditField
        function BetasfilenameEditFieldValueChanged(app, event)
            % Load betas from the specified file
            read_betas(app);
        end

        % Value changed function: StensorsfilenameEditField
        function StensorsfilenameEditFieldValueChanged(app, event)
            % Load SR from the specified file
            read_SR(app);
        end

        % Value changed function: GaindBmEditField
        function GaindBmEditFieldValueChanged(app, event)
            app.gaincoeff1mEditField.Value = app.GaindBmEditField.Value*log(10)/(10*app.FiberlengthmEditField.Value); % 1/m
        end

        % Value changed function: gaincoeff1mEditField
        function gaincoeff1mEditFieldValueChanged(app, event)
            app.GaindBmEditField.Value = app.gaincoeff1mEditField.Value*(10*app.FiberlengthmEditField.Value)/log(10); % dB/m
        end

        % Value changed function: UpperstatelifetimesEditField
        function UpperstatelifetimesEditFieldValueChanged(app, event)
            h = 6.626e-34; % Planck constant
            
            app.SaturationintensityJm2EditField.Value = h*app.c/(app.CenterwavelengthnmEditField.Value*1e-9)/app.Totalcrosssectionm2emissionabsorptionEditField.Value/app.UpperstatelifetimesEditField.Value/app.LaserrepetitionrateHzEditField.Value; % J/m^2
            app.SaturationenergynJEditField.Value = app.SaturationintensityJm2EditField.Value*app.Aeff*1e9; % nJ
        end

        % Value changed function: LaserrepetitionrateHzEditField
        function LaserrepetitionrateHzEditFieldValueChanged(app, event)
            h = 6.626e-34; % Planck constant
            
            app.SaturationintensityJm2EditField.Value = h*app.c/(app.CenterwavelengthnmEditField.Value*1e-9)/app.Totalcrosssectionm2emissionabsorptionEditField.Value/app.UpperstatelifetimesEditField.Value/app.LaserrepetitionrateHzEditField.Value; % J/m^2
            app.SaturationenergynJEditField.Value = app.SaturationintensityJm2EditField.Value*app.Aeff*1e9; % nJ
        end

        % Value changed function: 
        % Totalcrosssectionm2emissionabsorptionEditField
        function Totalcrosssectionm2emissionabsorptionEditFieldValueChanged(app, event)
            h = 6.626e-34; % Planck constant
            
            app.SaturationintensityJm2EditField.Value = h*app.c/(app.CenterwavelengthnmEditField.Value*1e-9)/app.Totalcrosssectionm2emissionabsorptionEditField.Value/app.UpperstatelifetimesEditField.Value/app.LaserrepetitionrateHzEditField.Value; % J/m^2
            app.SaturationenergynJEditField.Value = app.SaturationintensityJm2EditField.Value*app.Aeff*1e9; % nJ
        end

        % Value changed function: SavingstepsizemEditField
        function SavingstepsizemEditFieldValueChanged(app, event)
            if app.SavingstepsizemEditField.Value ~= 0
                reset_saving_period = false;
                num_saves_total = app.FiberlengthmEditField.Value/app.SavingstepsizemEditField.Value;
                if rem(num_saves_total,1) && rem(num_saves_total+eps(num_saves_total),1) && rem(num_saves_total-eps(num_saves_total),1)
                    errordlg(sprintf('The save period is %4.2f m and the fiber length is %4.2f m, which are not commensurate', app.SavingstepsizemEditField.Value,app.FiberlengthmEditField.Value),'Saving step size Incommemsurate Error','replace');
                    reset_saving_period = true; % reset
                end
                
                if isequal(app.AdaptivealgorithmButtonGroup.SelectedObject.Text,'fixed step size')
                    if isequal(app.SteppingalgorithmDropDown.Value,'MPA')
                        large_step = app.StepsizemEditField.Value*app.NumberofparallelizationsEditField.Value;
                    else
                        large_step = app.StepsizemEditField.Value;
                    end
                    num_steps_persave = app.SavingstepsizemEditField.Value/large_step;
                    if rem(num_steps_persave,1) && rem(num_steps_persave+eps(num_steps_persave),1) && rem(num_steps_persave-eps(num_steps_persave),1)
                        errordlg(sprintf('The step size is %4.2f m and the save period is %4.2f m, which are not commensurate', large_step,app.SavingstepsizemEditField.Value),'Saving step size Incommemsurate Error','replace');
                        reset_saving_period = true;
                    end
                end
                
                if reset_saving_period
                    app.SavingstepsizemEditField.Value = 0; % reset to 0
                end
            end
            
            maxdeltaZEditFieldValueChanged(app); % check the max adaptive step size again
        end

        % Value changed function: EllipticityEditField
        function EllipticityEditFieldValueChanged(app, event)
            if isequal(app.ScalarPolarizedsimulationsButtonGroup.SelectedObject.Text,'scalar')
                if app.EllipticityEditField.Value ~= 0 && app.EllipticityEditField.Value ~= 1
                    errordlg('Ellipticity can only be 0 (linearly polarized) or 1 (circularly polarized) in scalar computations.','Ellipticity Error','replace');
                    app.EllipticityEditField.Value = 0; % reset to linear polarization
                end
            end
        end

        % Selection changed function: TimewindowBetasButtonGroup
        function TimewindowBetasButtonGroupSelectionChanged(app, event)
            switch app.TimewindowBetasButtonGroup.SelectedObject.Text
                case 'Beta0'
                    app.ValueEditField.Value = app.sim_betas(1);
                    app.mLabel_2.Text = '1/m';
                case 'Beta1'
                    app.ValueEditField.Value = app.sim_betas(2);
                    app.mLabel_2.Text = 'ps/m';
            end
        end

        % Selection changed function: 
        % ScalarPolarizedsimulationsButtonGroup
        function ScalarPolarizedsimulationsButtonGroupSelectionChanged(app, event)
            if app.singlemodeButton.Value
                switch app.ScalarPolarizedsimulationsButtonGroup.SelectedObject.Text
                    case 'scalar'
                        app.YEditFieldLabel.Enable = 'off';
                        app.YEditField.Enable = 'off';
                        
                        if app.EllipticityEditField.Value ~= 0 && app.EllipticityEditField.Value ~= 1
                            errordlg('Ellipticity can only be 0 (linearly polarized) or 1 (circularly polarized) in scalar computations.','Ellipticity Error','replace');
                            app.EllipticityEditField.Value = 0; % reset to linear polarization
                        end
                    case 'polarized'
                        app.YEditFieldLabel.Enable = 'on';
                        app.YEditField.Enable = 'on';
                end
            end
        end

        % Selection changed function: AdaptivealgorithmButtonGroup
        function AdaptivealgorithmButtonGroupSelectionChanged(app, event)
            switch app.AdaptivealgorithmButtonGroup.SelectedObject.Text
                case 'adaptive step size'
                    app.StepsizemEditFieldLabel.Enable = 'off';
                    app.StepsizemEditField.Enable = 'off';
                    app.ThresholdEditFieldLabel.Enable = 'on';
                    app.ThresholdEditField.Enable = 'on';
                    app.maxdeltaZEditField.Enable = 'on';
                    app.maxdeltaZEditFieldLabel.Enable = 'on';
                    
                    app.SteppingalgorithmDropDown.Value = 'RK4IP'; % Adaptive algorithm supports only RK4IP.
                    app.NumberofparallelizationsEditFieldLabel.Enable = 'off';
                    app.NumberofparallelizationsEditField.Enable = 'off';
                    app.MaxiterationsEditFieldLabel.Enable = 'off';
                    app.MaxiterationsEditField.Enable = 'off';
                    app.MiniterationsEditFieldLabel.Enable = 'off';
                    app.MiniterationsEditField.Enable = 'off';
                    app.ToleranceEditFieldLabel.Enable = 'off';
                    app.ToleranceEditField.Enable = 'off';
                case 'fixed step size'
                    app.StepsizemEditFieldLabel.Enable = 'on';
                    app.StepsizemEditField.Enable = 'on';
                    app.ThresholdEditFieldLabel.Enable = 'off';
                    app.ThresholdEditField.Enable = 'off';
                    app.maxdeltaZEditField.Enable = 'off';
                    app.maxdeltaZEditFieldLabel.Enable = 'off';
            end
        end

        % Value changed function: UseGPUButton
        function UseGPUButtonValueChanged(app, event)
            if app.UseGPUButton.Value
                try
                    gpuDevice(); % check if it has an available GPU
                    
                    app.GPUindextouseEditFieldLabel.Enable = 'on';
                    app.GPUindextouseEditField.Enable = 'on';
                    app.CudafolderEditFieldLabel.Enable = 'on';
                    app.CudafolderEditField.Enable = 'on';
                catch
                    app.UseGPUButton.Value = false;
                    
                    app.GPUindextouseEditFieldLabel.Enable = 'off';
                    app.GPUindextouseEditField.Enable = 'off';
                    app.CudafolderEditFieldLabel.Enable = 'off';
                    app.CudafolderEditField.Enable = 'off';
                end
            else
                app.GPUindextouseEditFieldLabel.Enable = 'off';
                app.GPUindextouseEditField.Enable = 'off';
                app.CudafolderEditFieldLabel.Enable = 'off';
                app.CudafolderEditField.Enable = 'off';
            end
        end

        % Value changed function: ShowprogressbarButton
        function ShowprogressbarButtonValueChanged(app, event)
            if app.ShowprogressbarButton.Value
                app.ProgressbarnameEditFieldLabel.Enable = 'on';
                app.ProgressbarnameEditField.Enable = 'on';
            else
                app.ProgressbarnameEditFieldLabel.Enable = 'off';
                app.ProgressbarnameEditField.Enable = 'off';
            end
        end

        % Value changed function: SteppingalgorithmDropDown
        function SteppingalgorithmDropDownValueChanged(app, event)
            switch app.AdaptivealgorithmButtonGroup.SelectedObject.Text
                case 'adaptive step size'
                    app.SteppingalgorithmDropDown.Value = 'RK4IP';
                    
                    warndlg('Adaptive-step algorithm supports only RK4IP.','Stepping algorithm','replace');
                case 'fixed step size'
                    if isequal(app.SteppingalgorithmDropDown.Value,'MPA')
                        app.NumberofparallelizationsEditFieldLabel.Enable = 'on';
                        app.NumberofparallelizationsEditField.Enable = 'on';
                        app.MaxiterationsEditFieldLabel.Enable = 'on';
                        app.MaxiterationsEditField.Enable = 'on';
                        app.MiniterationsEditFieldLabel.Enable = 'on';
                        app.MiniterationsEditField.Enable = 'on';
                        app.ToleranceEditFieldLabel.Enable = 'on';
                        app.ToleranceEditField.Enable = 'on';
                    end
            end
        end

        % Value changed function: GainmodelDropDown
        function GainmodelDropDownValueChanged(app, event)
            if ismember(app.GainmodelDropDown.Value,1:3)
                app.GaindBmEditFieldLabel.Enable = 'on';
                app.GaindBmEditField.Enable = 'on';
                app.gaincoeff1mEditFieldLabel.Enable = 'on';
                app.gaincoeff1mEditField.Enable = 'on';
                app.FWHMnmEditFieldLabel.Enable = 'on';
                app.FWHMnmEditField.Enable = 'on';
                switch app.GainmodelDropDown.Value
                    case 1
                        app.SaturationenergynJEditFieldLabel.Enable = 'on';
                        app.SaturationenergynJEditField.Enable = 'on';
                        
                        app.SaturationintensityJm2EditFieldLabel.Enable = 'off';
                        app.SaturationintensityJm2EditField.Enable = 'off';
                    case {2,3}
                        app.SaturationintensityJm2EditFieldLabel.Enable = 'on';
                        app.SaturationintensityJm2EditField.Enable = 'on';
                        
                        app.SaturationenergynJEditFieldLabel.Enable = 'off';
                        app.SaturationenergynJEditField.Enable = 'off';
                end
                app.UpperstatelifetimesEditFieldLabel.Enable = 'on';
                app.UpperstatelifetimesEditField.Enable = 'on';
                app.LaserrepetitionrateHzEditFieldLabel.Enable = 'on';
                app.LaserrepetitionrateHzEditField.Enable = 'on';
                app.Totalcrosssectionm2emissionabsorptionEditFieldLabel.Enable = 'on';
                app.Totalcrosssectionm2emissionabsorptionEditField.Enable = 'on';
            else
                app.GaindBmEditFieldLabel.Enable = 'off';
                app.GaindBmEditField.Enable = 'off';
                app.gaincoeff1mEditFieldLabel.Enable = 'off';
                app.gaincoeff1mEditField.Enable = 'off';
                app.FWHMnmEditFieldLabel.Enable = 'off';
                app.FWHMnmEditField.Enable = 'off';
                app.SaturationenergynJEditFieldLabel.Enable = 'off';
                app.SaturationenergynJEditField.Enable = 'off';
                app.SaturationintensityJm2EditFieldLabel.Enable = 'off';
                app.SaturationintensityJm2EditField.Enable = 'off';
                app.UpperstatelifetimesEditFieldLabel.Enable = 'off';
                app.UpperstatelifetimesEditField.Enable = 'off';
                app.LaserrepetitionrateHzEditFieldLabel.Enable = 'off';
                app.LaserrepetitionrateHzEditField.Enable = 'off';
                app.Totalcrosssectionm2emissionabsorptionEditFieldLabel.Enable = 'off';
                app.Totalcrosssectionm2emissionabsorptionEditField.Enable = 'off';
            end
        end

        % Value changed function: GPUindextouseEditField
        function GPUindextouseEditFieldValueChanged(app, event)
            num_GPU = gpuDeviceCount("available");
            
            if app.GPUindextouseEditField.Value > num_GPU
                errordlg(sprintf('It needs to be smaller than or equal to %u.',num_GPU));
                app.GPUindextouseEditField.Value = num_GPU;
            end
        end

        % Value changed function: ValueEditField
        function ValueEditFieldValueChanged(app, event)
            switch app.BetasButtonGroup.SelectedObject.Text
                case 'Beta0'
                    app.sim_betas(1) = app.ValueEditField.Value;
                case 'Beta1'
                    app.sim_betas(2) = app.ValueEditField.Value;
            end
        end

        % Value changed function: FibermaterialDropDown
        function FibermaterialDropDownValueChanged(app, event)
            switch app.FibermaterialDropDown.Value
                case 'silica'
                    app.Nonlinearrefractiveindexn2m2WEditField.Value = 2.3e-20; % m^2/W
                case 'calcogenide'
                    app.Nonlinearrefractiveindexn2m2WEditField.Value = 1.7e-18; % m^2/W
                case 'ZBLAN'
                    app.Nonlinearrefractiveindexn2m2WEditField.Value = 2.1e-20; % m^2/W
            end
        end

        % Value changed function: StepsizemEditField
        function StepsizemEditFieldValueChanged(app, event)
            if isequal(app.SteppingalgorithmDropDown.Value,'MPA')
                large_step = app.NumberofparallelizationsEditField.Value*app.StepsizemEditField.Value;
            else
                large_step = app.StepsizemEditField.Value;
            end
            num_steps_total = app.FiberlengthmEditField.Value/large_step;
            if rem(num_steps_total,1) && rem(num_steps_total+eps(num_steps_total),1) && rem(num_steps_total-eps(num_steps_total),1)
                errordlg(sprintf('The step size is %4.2f m and the fiber length is %4.2f m, which are not commensurate', large_step,app.FiberlengthmEditField.Value),'Step size Incommemsurate Error','replace');
                app.StepsizemEditField.Value = app.FiberlengthmEditField.Value/round(num_steps_total);
                if isequal(app.SteppingalgorithmDropDown.Value,'MPA')
                    app.StepsizemEditField.Value = app.StepsizemEditField.Value/app.NumberofparallelizationsEditField.Value;
                end
            end
        end

        % Value changed function: NumberofparallelizationsEditField
        function NumberofparallelizationsEditFieldValueChanged(app, event)
            StepsizemEditFieldValueChanged(app);
            SavingstepsizemEditFieldValueChanged(app);
        end

        % Value changed function: FiberlengthmEditField
        function FiberlengthmEditFieldValueChanged(app, event)
            SavingstepsizemEditFieldValueChanged(app);
            StepsizemEditFieldValueChanged(app);
            maxdeltaZEditFieldValueChanged(app); % check the max adaptive step size again
            
            GaindBmEditFieldValueChanged(app);
            gaincoeff1mEditFieldValueChanged(app);
        end

        % Value changed function: maxdeltaZEditField
        function maxdeltaZEditFieldValueChanged(app, event)
            if app.SavingstepsizemEditField.Value == 0
                save_step_size = app.FiberlengthmEditField.Value;
            else
                save_step_size = app.SavingstepsizemEditField.Value;
            end
            
            % The saved step size can't be too small; otherwise, it'll skip
            % saving the data and send an error.
            max_maxDeltaZ = save_step_size/10;
            
            if app.maxdeltaZEditField.Value > max_maxDeltaZ || app.maxdeltaZEditField.Value == 0
                app.maxdeltaZEditField.Value = max_maxDeltaZ;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GMMNLSE_propagateinputparametersLabel = uilabel(app.UIFigure);
            app.GMMNLSE_propagateinputparametersLabel.Text = 'GMMNLSE_propagate() input parameters';
            app.CenterfrequencyTHzEditFieldLabel = uilabel(app.UIFigure);
            app.CenterfrequencyTHzEditFieldLabel.Tooltip = {''};
            app.CenterfrequencyTHzEditFieldLabel.Text = 'Center frequency (THz)';
            app.CenterfrequencyTHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterfrequencyTHzEditField.Limits = [0 Inf];
            app.CenterfrequencyTHzEditField.ValueChangedFcn = createCallbackFcn(app, @CenterfrequencyTHzEditFieldValueChanged, true);
            app.CenterfrequencyTHzEditField.Tooltip = {'sim.f0'};
            app.CenterfrequencyTHzEditField.Value = 291.06;
            app.CenterwavelengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.CenterwavelengthnmEditFieldLabel.Tooltip = {''};
            app.CenterwavelengthnmEditFieldLabel.Text = 'Center wavelength (nm)';
            app.CenterwavelengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterwavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @CenterwavelengthnmEditFieldValueChanged, true);
            app.CenterwavelengthnmEditField.Tooltip = {'sim.lambda0'};
            app.CenterwavelengthnmEditField.Value = 1030;
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.GainmodelTab = uitab(app.TabGroup2);
            app.GainmodelTab.Title = 'Gain model';
            app.GaindBmEditFieldLabel = uilabel(app.GainmodelTab);
            app.GaindBmEditFieldLabel.Enable = 'off';
            app.GaindBmEditFieldLabel.Text = 'Gain (dB/m)';
            app.GaindBmEditField = uieditfield(app.GainmodelTab, 'numeric');
            app.GaindBmEditField.ValueChangedFcn = createCallbackFcn(app, @GaindBmEditFieldValueChanged, true);
            app.GaindBmEditField.Enable = 'off';
            app.GaindBmEditField.Tooltip = {'fiber.dB_gain'};
            app.GaindBmEditField.Value = 30;
            app.gaincoeff1mEditFieldLabel = uilabel(app.GainmodelTab);
            app.gaincoeff1mEditFieldLabel.Enable = 'off';
            app.gaincoeff1mEditFieldLabel.Text = 'gain coeff (1/m)';
            app.gaincoeff1mEditField = uieditfield(app.GainmodelTab, 'numeric');
            app.gaincoeff1mEditField.Limits = [0 Inf];
            app.gaincoeff1mEditField.ValueChangedFcn = createCallbackFcn(app, @gaincoeff1mEditFieldValueChanged, true);
            app.gaincoeff1mEditField.Enable = 'off';
            app.gaincoeff1mEditField.Tooltip = {'fiber.gain_coeff=fiber.dB_gain*log(10)/(10*fiber.L0)'};
            app.gaincoeff1mEditField.Value = 6.90775527898214;
            app.FWHMnmEditFieldLabel = uilabel(app.GainmodelTab);
            app.FWHMnmEditFieldLabel.Enable = 'off';
            app.FWHMnmEditFieldLabel.Text = 'FWHM (nm)';
            app.FWHMnmEditField = uieditfield(app.GainmodelTab, 'numeric');
            app.FWHMnmEditField.Limits = [0 Inf];
            app.FWHMnmEditField.Enable = 'off';
            app.FWHMnmEditField.Tooltip = {'fiber.gain_fwhm'};
            app.FWHMnmEditField.Value = 40;
            app.TabGroup3 = uitabgroup(app.GainmodelTab);
            app.EnergyTab = uitab(app.TabGroup3);
            app.EnergyTab.Title = 'Energy';
            app.SaturationenergynJEditFieldLabel = uilabel(app.EnergyTab);
            app.SaturationenergynJEditFieldLabel.Enable = 'off';
            app.SaturationenergynJEditFieldLabel.Text = 'Saturation energy (nJ)';
            app.SaturationenergynJEditField = uieditfield(app.EnergyTab, 'numeric');
            app.SaturationenergynJEditField.Limits = [0 Inf];
            app.SaturationenergynJEditField.Enable = 'off';
            app.SaturationenergynJEditField.Tooltip = {'fiber.saturation_energy'};
            app.SaturationenergynJEditField.Value = 0.335683395724872;
            app.IntensityTab = uitab(app.TabGroup3);
            app.IntensityTab.Title = 'Intensity';
            app.SaturationintensityJm2EditFieldLabel = uilabel(app.IntensityTab);
            app.SaturationintensityJm2EditFieldLabel.Enable = 'off';
            app.SaturationintensityJm2EditFieldLabel.Text = 'Saturation intensity (J/m^2)';
            app.SaturationintensityJm2EditField = uieditfield(app.IntensityTab, 'numeric');
            app.SaturationintensityJm2EditField.Limits = [0 Inf];
            app.SaturationintensityJm2EditField.Enable = 'off';
            app.SaturationintensityJm2EditField.Tooltip = {'fiber.saturation_intensity'};
            app.SaturationintensityJm2EditField.Value = 11.1187662317349;
            app.DerivationfromscratchTab = uitab(app.TabGroup3);
            app.DerivationfromscratchTab.Title = 'Derivation from scratch';
            app.UpperstatelifetimesEditFieldLabel = uilabel(app.DerivationfromscratchTab);
            app.UpperstatelifetimesEditFieldLabel.Enable = 'off';
            app.UpperstatelifetimesEditFieldLabel.Text = 'Upper-state lifetime (s)';
            app.UpperstatelifetimesEditField = uieditfield(app.DerivationfromscratchTab, 'numeric');
            app.UpperstatelifetimesEditField.Limits = [0 Inf];
            app.UpperstatelifetimesEditField.ValueChangedFcn = createCallbackFcn(app, @UpperstatelifetimesEditFieldValueChanged, true);
            app.UpperstatelifetimesEditField.Enable = 'off';
            app.UpperstatelifetimesEditField.Tooltip = {'fiber.gain_tau'};
            app.UpperstatelifetimesEditField.Value = 0.00084;
            app.LaserrepetitionrateHzEditFieldLabel = uilabel(app.DerivationfromscratchTab);
            app.LaserrepetitionrateHzEditFieldLabel.Enable = 'off';
            app.LaserrepetitionrateHzEditFieldLabel.Text = 'Laser repetition rate (Hz)';
            app.LaserrepetitionrateHzEditField = uieditfield(app.DerivationfromscratchTab, 'numeric');
            app.LaserrepetitionrateHzEditField.Limits = [0 Inf];
            app.LaserrepetitionrateHzEditField.ValueChangedFcn = createCallbackFcn(app, @LaserrepetitionrateHzEditFieldValueChanged, true);
            app.LaserrepetitionrateHzEditField.Enable = 'off';
            app.LaserrepetitionrateHzEditField.Tooltip = {'fiber.t_rep'};
            app.LaserrepetitionrateHzEditField.Value = 30000000;
            app.Totalcrosssectionm2emissionabsorptionEditFieldLabel = uilabel(app.DerivationfromscratchTab);
            app.Totalcrosssectionm2emissionabsorptionEditFieldLabel.Enable = 'off';
            app.Totalcrosssectionm2emissionabsorptionEditFieldLabel.Tooltip = {'fiber.gain_cross_section'};
            app.Totalcrosssectionm2emissionabsorptionEditFieldLabel.Text = {'Total cross section (m^2)'; '(emission + absorption)'};
            app.Totalcrosssectionm2emissionabsorptionEditField = uieditfield(app.DerivationfromscratchTab, 'numeric');
            app.Totalcrosssectionm2emissionabsorptionEditField.Limits = [0 Inf];
            app.Totalcrosssectionm2emissionabsorptionEditField.ValueChangedFcn = createCallbackFcn(app, @Totalcrosssectionm2emissionabsorptionEditFieldValueChanged, true);
            app.Totalcrosssectionm2emissionabsorptionEditField.Enable = 'off';
            app.Totalcrosssectionm2emissionabsorptionEditField.Tooltip = {'fiber.gain_cross_section'};
            app.Totalcrosssectionm2emissionabsorptionEditField.Value = 6.883e-25;
            app.MPATab = uitab(app.TabGroup2);
            app.MPATab.Tooltip = {'Parallelization algorithm for multimode simulations'};
            app.MPATab.Title = 'MPA';
            app.NumberofparallelizationsEditFieldLabel = uilabel(app.MPATab);
            app.NumberofparallelizationsEditFieldLabel.Enable = 'off';
            app.NumberofparallelizationsEditFieldLabel.Tooltip = {''};
            app.NumberofparallelizationsEditFieldLabel.Text = 'Number of parallelizations';
            app.NumberofparallelizationsEditField = uieditfield(app.MPATab, 'numeric');
            app.NumberofparallelizationsEditField.Limits = [1 20];
            app.NumberofparallelizationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofparallelizationsEditFieldValueChanged, true);
            app.NumberofparallelizationsEditField.Enable = 'off';
            app.NumberofparallelizationsEditField.Tooltip = {'sim.MPA.M'};
            app.NumberofparallelizationsEditField.Value = 10;
            app.MaxiterationsEditFieldLabel = uilabel(app.MPATab);
            app.MaxiterationsEditFieldLabel.Enable = 'off';
            app.MaxiterationsEditFieldLabel.Tooltip = {''};
            app.MaxiterationsEditFieldLabel.Text = 'Max iterations';
            app.MaxiterationsEditField = uieditfield(app.MPATab, 'numeric');
            app.MaxiterationsEditField.Limits = [1 Inf];
            app.MaxiterationsEditField.Enable = 'off';
            app.MaxiterationsEditField.Tooltip = {'sim.MPA.n_tot_max'};
            app.MaxiterationsEditField.Value = 20;
            app.MiniterationsEditFieldLabel = uilabel(app.MPATab);
            app.MiniterationsEditFieldLabel.Enable = 'off';
            app.MiniterationsEditFieldLabel.Tooltip = {''};
            app.MiniterationsEditFieldLabel.Text = 'Min iterations';
            app.MiniterationsEditField = uieditfield(app.MPATab, 'numeric');
            app.MiniterationsEditField.Limits = [1 Inf];
            app.MiniterationsEditField.Enable = 'off';
            app.MiniterationsEditField.Tooltip = {'sim.MPA.n_tot_min'};
            app.MiniterationsEditField.Value = 2;
            app.ToleranceEditFieldLabel = uilabel(app.MPATab);
            app.ToleranceEditFieldLabel.Enable = 'off';
            app.ToleranceEditFieldLabel.Tooltip = {''};
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.MPATab, 'numeric');
            app.ToleranceEditField.Limits = [0 Inf];
            app.ToleranceEditField.Enable = 'off';
            app.ToleranceEditField.Tooltip = {'sim.MPA.tol'};
            app.ToleranceEditField.Value = 0.0001;
            app.AdaptivealgorithmTab = uitab(app.TabGroup2);
            app.AdaptivealgorithmTab.Title = 'Adaptive algorithm';
            app.ThresholdEditFieldLabel = uilabel(app.AdaptivealgorithmTab);
            app.ThresholdEditFieldLabel.Tooltip = {''};
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.AdaptivealgorithmTab, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.Tooltip = {'sim.adaptive_deltaZ.threshold'; 'Recommended value is less than 1e-5. A value larger than 1e-3 is too large.'};
            app.ThresholdEditField.Value = 1e-06;
            app.maxdeltaZEditFieldLabel = uilabel(app.AdaptivealgorithmTab);
            app.maxdeltaZEditFieldLabel.Text = 'max deltaZ';
            app.maxdeltaZEditField = uieditfield(app.AdaptivealgorithmTab, 'numeric');
            app.maxdeltaZEditField.Limits = [0 Inf];
            app.maxdeltaZEditField.ValueChangedFcn = createCallbackFcn(app, @maxdeltaZEditFieldValueChanged, true);
            app.RamanTab = uitab(app.TabGroup2);
            app.RamanTab.Title = 'Raman';
            app.IncludespontaneousRamanButton = uibutton(app.RamanTab, 'state');
            app.IncludespontaneousRamanButton.Tooltip = {'sim.Raman_sponRS'};
            app.IncludespontaneousRamanButton.Text = 'Include spontaneous Raman';
            app.IncludespontaneousRamanButton.Value = true;
            app.GPUTab = uitab(app.TabGroup2);
            app.GPUTab.Title = 'GPU';
            app.GPUindextouseEditFieldLabel = uilabel(app.GPUTab);
            app.GPUindextouseEditFieldLabel.Tooltip = {''};
            app.GPUindextouseEditFieldLabel.Text = 'GPU index to use';
            app.GPUindextouseEditField = uieditfield(app.GPUTab, 'numeric');
            app.GPUindextouseEditField.Limits = [1 Inf];
            app.GPUindextouseEditField.ValueChangedFcn = createCallbackFcn(app, @GPUindextouseEditFieldValueChanged, true);
            app.GPUindextouseEditField.Tooltip = {'sim.gpuDevice.Index'};
            app.GPUindextouseEditField.Value = 1;
            app.CudafolderEditFieldLabel = uilabel(app.GPUTab);
            app.CudafolderEditFieldLabel.Text = 'Cuda folder';
            app.CudafolderEditField = uieditfield(app.GPUTab, 'text');
            app.CudafolderEditField.Value = 'GMMNLSE/cuda';
            app.ProgressbarTab = uitab(app.TabGroup2);
            app.ProgressbarTab.Title = 'Progress bar';
            app.ProgressbarnameEditFieldLabel = uilabel(app.ProgressbarTab);
            app.ProgressbarnameEditFieldLabel.Text = 'Progress-bar name';
            app.ProgressbarnameEditField = uieditfield(app.ProgressbarTab, 'text');
            app.ProgressbarnameEditField.Tooltip = {'sim.progress_bar_name'};
            app.SimulationtypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SimulationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SimulationtypeButtonGroupSelectionChanged, true);
            app.SimulationtypeButtonGroup.Title = 'Simulation type';
            app.singlemodeButton = uiradiobutton(app.SimulationtypeButtonGroup);
            app.singlemodeButton.Text = 'single mode';
            app.singlemodeButton.Value = true;
            app.multimodeButton = uiradiobutton(app.SimulationtypeButtonGroup);
            app.multimodeButton.Text = 'multimode';
            app.IncludeshocktermButton = uibutton(app.UIFigure, 'state');
            app.IncludeshocktermButton.Tooltip = {'sim.sw'};
            app.IncludeshocktermButton.Text = 'Include shock term';
            app.IncludeshocktermButton.Value = true;
            app.StepsizemEditFieldLabel = uilabel(app.UIFigure);
            app.StepsizemEditFieldLabel.Enable = 'off';
            app.StepsizemEditFieldLabel.Tooltip = {''};
            app.StepsizemEditFieldLabel.Text = 'Step size (m)';
            app.StepsizemEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepsizemEditField.Limits = [0 Inf];
            app.StepsizemEditField.ValueChangedFcn = createCallbackFcn(app, @StepsizemEditFieldValueChanged, true);
            app.StepsizemEditField.Enable = 'off';
            app.StepsizemEditField.Tooltip = {'sim.deltaZ'; 'This is used only with a fixed step size.'};
            app.StepsizemEditField.Value = 0.001;
            app.SavingstepsizemEditFieldLabel = uilabel(app.UIFigure);
            app.SavingstepsizemEditFieldLabel.Tooltip = {''};
            app.SavingstepsizemEditFieldLabel.Text = 'Saving step size (m)';
            app.SavingstepsizemEditField = uieditfield(app.UIFigure, 'numeric');
            app.SavingstepsizemEditField.Limits = [0 Inf];
            app.SavingstepsizemEditField.ValueChangedFcn = createCallbackFcn(app, @SavingstepsizemEditFieldValueChanged, true);
            app.SavingstepsizemEditField.Tooltip = {'sim.save_period'; '0 = only save input and output (saving step = fiber length)'};
            app.ScalarPolarizedsimulationsButtonGroup = uibuttongroup(app.UIFigure);
            app.ScalarPolarizedsimulationsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScalarPolarizedsimulationsButtonGroupSelectionChanged, true);
            app.ScalarPolarizedsimulationsButtonGroup.Tooltip = {'sim.scalar'; 'Make sure your fields, betas, and SR have the correct dimensions.'};
            app.ScalarPolarizedsimulationsButtonGroup.Title = 'Scalar/Polarized simulations';
            app.scalarButton = uiradiobutton(app.ScalarPolarizedsimulationsButtonGroup);
            app.scalarButton.Text = 'scalar';
            app.scalarButton.Value = true;
            app.polarizedButton = uiradiobutton(app.ScalarPolarizedsimulationsButtonGroup);
            app.polarizedButton.Text = 'polarized';
            app.EllipticityEditFieldLabel = uilabel(app.UIFigure);
            app.EllipticityEditFieldLabel.Tooltip = {''};
            app.EllipticityEditFieldLabel.Text = 'Ellipticity';
            app.EllipticityEditField = uieditfield(app.UIFigure, 'numeric');
            app.EllipticityEditField.Limits = [0 1];
            app.EllipticityEditField.ValueChangedFcn = createCallbackFcn(app, @EllipticityEditFieldValueChanged, true);
            app.EllipticityEditField.Tooltip = {'sim.ellipticity'; '1 = linear polarization; 0 = circular polarization'};
            app.AdaptivealgorithmButtonGroup = uibuttongroup(app.UIFigure);
            app.AdaptivealgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AdaptivealgorithmButtonGroupSelectionChanged, true);
            app.AdaptivealgorithmButtonGroup.Tooltip = {'sim.adaptive_deltaZ.model'};
            app.AdaptivealgorithmButtonGroup.Title = 'Adaptive algorithm';
            app.adaptivestepsizeButton = uiradiobutton(app.AdaptivealgorithmButtonGroup);
            app.adaptivestepsizeButton.Text = 'adaptive step size';
            app.adaptivestepsizeButton.Value = true;
            app.fixedstepsizeButton = uiradiobutton(app.AdaptivealgorithmButtonGroup);
            app.fixedstepsizeButton.Text = 'fixed step size';
            app.NumericalprecisionButtonGroup = uibuttongroup(app.UIFigure);
            app.NumericalprecisionButtonGroup.Tooltip = {'sim.single_yes'; 'Single precision doesn''t help boost the computation speed a lot.'};
            app.NumericalprecisionButtonGroup.Title = 'Numerical precision';
            app.singleButton = uiradiobutton(app.NumericalprecisionButtonGroup);
            app.singleButton.Text = 'single';
            app.doubleButton = uiradiobutton(app.NumericalprecisionButtonGroup);
            app.doubleButton.Text = 'double';
            app.doubleButton.Value = true;
            app.RamanmodelDropDownLabel = uilabel(app.UIFigure);
            app.RamanmodelDropDownLabel.Text = 'Raman model';
            app.RamanmodelDropDown = uidropdown(app.UIFigure);
            app.RamanmodelDropDown.Items = {'No Raman', 'isotropic Raman', 'anisotropic Raman'};
            app.RamanmodelDropDown.ItemsData = [0 1 2];
            app.RamanmodelDropDown.Tooltip = {'sim.Raman_model'};
            app.RamanmodelDropDown.Value = 1;
            app.SteppingalgorithmDropDownLabel = uilabel(app.UIFigure);
            app.SteppingalgorithmDropDownLabel.Text = 'Stepping algorithm';
            app.SteppingalgorithmDropDown = uidropdown(app.UIFigure);
            app.SteppingalgorithmDropDown.Items = {'split-step', 'RK4IP', 'MPA'};
            app.SteppingalgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @SteppingalgorithmDropDownValueChanged, true);
            app.SteppingalgorithmDropDown.Tooltip = {'sim.step_method'};
            app.SteppingalgorithmDropDown.Value = 'RK4IP';
            app.UseGPUButton = uibutton(app.UIFigure, 'state');
            app.UseGPUButton.ValueChangedFcn = createCallbackFcn(app, @UseGPUButtonValueChanged, true);
            app.UseGPUButton.Tooltip = {'sim.gpu_yes'};
            app.UseGPUButton.Text = 'Use GPU';
            app.UseGPUButton.Value = true;
            app.CenterthepulsewrtthetimewindowButton = uibutton(app.UIFigure, 'state');
            app.CenterthepulsewrtthetimewindowButton.Tooltip = {'sim.pulse_centering'; 'The offset time delay will be saved in the output for recovery.'};
            app.CenterthepulsewrtthetimewindowButton.Text = 'Center the pulse w.r.t. the time window';
            app.CenterthepulsewrtthetimewindowButton.Value = true;
            app.PhotonnoiseperfreqbandLabel = uilabel(app.UIFigure);
            app.PhotonnoiseperfreqbandLabel.Tooltip = {''};
            app.PhotonnoiseperfreqbandLabel.Text = '#Photon noise per freq band';
            app.PhotonnoiseperfreqbandEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhotonnoiseperfreqbandEditField.Limits = [0 Inf];
            app.PhotonnoiseperfreqbandEditField.Tooltip = {''};
            app.GainmodelDropDownLabel = uilabel(app.UIFigure);
            app.GainmodelDropDownLabel.Text = 'Gain model';
            app.GainmodelDropDown = uidropdown(app.UIFigure);
            app.GainmodelDropDown.Items = {'No gain', 'SM gain', 'New gain', 'Taylor gain', 'Rate-equation gain'};
            app.GainmodelDropDown.ItemsData = [0 1 2 3 4];
            app.GainmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @GainmodelDropDownValueChanged, true);
            app.GainmodelDropDown.Tooltip = {'sim.gain_model'};
            app.GainmodelDropDown.Value = 0;
            app.FiberPanel = uipanel(app.UIFigure);
            app.FiberPanel.Title = 'Fiber';
            app.FiberlengthmEditFieldLabel = uilabel(app.FiberPanel);
            app.FiberlengthmEditFieldLabel.Tooltip = {''};
            app.FiberlengthmEditFieldLabel.Text = 'Fiber length (m)';
            app.FiberlengthmEditField = uieditfield(app.FiberPanel, 'numeric');
            app.FiberlengthmEditField.Limits = [0 Inf];
            app.FiberlengthmEditField.ValueChangedFcn = createCallbackFcn(app, @FiberlengthmEditFieldValueChanged, true);
            app.FiberlengthmEditField.Tooltip = {'fiber.L0'};
            app.FiberlengthmEditField.Value = 1;
            app.Nonlinearrefractiveindexn2m2WEditFieldLabel = uilabel(app.FiberPanel);
            app.Nonlinearrefractiveindexn2m2WEditFieldLabel.Tooltip = {''};
            app.Nonlinearrefractiveindexn2m2WEditFieldLabel.Text = 'Nonlinear refractive index n2 (m^2/W)';
            app.Nonlinearrefractiveindexn2m2WEditField = uieditfield(app.FiberPanel, 'numeric');
            app.Nonlinearrefractiveindexn2m2WEditField.Tooltip = {'fiber.n2'};
            app.Nonlinearrefractiveindexn2m2WEditField.Value = 2.3e-20;
            app.FibermaterialDropDownLabel = uilabel(app.FiberPanel);
            app.FibermaterialDropDownLabel.Text = 'Fiber material';
            app.FibermaterialDropDown = uidropdown(app.FiberPanel);
            app.FibermaterialDropDown.Items = {'silica', 'calcogenide', 'ZBLAN'};
            app.FibermaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @FibermaterialDropDownValueChanged, true);
            app.FibermaterialDropDown.Tooltip = {'fiber.material'; 'This controls which Raman model to use.'};
            app.FibermaterialDropDown.Value = 'silica';
            app.TabGroup = uitabgroup(app.FiberPanel);
            app.SinglemodeTab = uitab(app.TabGroup);
            app.SinglemodeTab.Title = 'Single mode';
            app.MFDumEditField_2Label = uilabel(app.SinglemodeTab);
            app.MFDumEditField_2Label.Text = 'MFD (um)';
            app.MFDumEditField_2 = uieditfield(app.SinglemodeTab, 'numeric');
            app.MFDumEditField_2.Limits = [0 Inf];
            app.MFDumEditField_2.ValueChangedFcn = createCallbackFcn(app, @MFDumEditField_2ValueChanged, true);
            app.MFDumEditField_2.Tooltip = {'fiber.MFD'; 'Mode-field diameter'};
            app.MFDumEditField_2.Value = 5.95;
            app.BetasButtonGroup = uibuttongroup(app.SinglemodeTab);
            app.BetasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BetasButtonGroupSelectionChanged, true);
            app.BetasButtonGroup.Tooltip = {'fiber.betas'; 'Taylor-expansion coefficients of the propagation constant over the center angular frequency'};
            app.BetasButtonGroup.Title = 'Betas';
            app.Beta0Button = uitogglebutton(app.BetasButtonGroup);
            app.Beta0Button.Text = 'Beta0';
            app.Beta0Button.Value = true;
            app.Beta1Button = uitogglebutton(app.BetasButtonGroup);
            app.Beta1Button.Text = 'Beta1';
            app.Beta2Button = uitogglebutton(app.BetasButtonGroup);
            app.Beta2Button.Text = 'Beta2';
            app.Beta3Button = uitogglebutton(app.BetasButtonGroup);
            app.Beta3Button.Text = 'Beta3';
            app.Beta4Button = uitogglebutton(app.BetasButtonGroup);
            app.Beta4Button.Text = 'Beta4';
            app.mLabel = uilabel(app.BetasButtonGroup);
            app.mLabel.Text = '1/m';
            app.mLabel_3 = uilabel(app.BetasButtonGroup);
            app.mLabel_3.Enable = 'off';
            app.mLabel_3.Text = '1/m';
            app.DefaultButton = uibutton(app.BetasButtonGroup, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Tooltip = {'This code saves two betas based on normal or anomalous dispersion.'};
            app.DefaultButton.Text = 'Default';
            app.XEditFieldLabel = uilabel(app.BetasButtonGroup);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.BetasButtonGroup, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.BetasButtonGroup);
            app.YEditFieldLabel.Enable = 'off';
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.BetasButtonGroup, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Enable = 'off';
            app.MultimodeTab = uitab(app.TabGroup);
            app.MultimodeTab.Title = 'Multimode';
            app.modeindexEditFieldLabel = uilabel(app.MultimodeTab);
            app.modeindexEditFieldLabel.Enable = 'off';
            app.modeindexEditFieldLabel.Text = 'mode index';
            app.modeindexEditField = uieditfield(app.MultimodeTab, 'text');
            app.modeindexEditField.ValueChangedFcn = createCallbackFcn(app, @modeindexEditFieldValueChanged, true);
            app.modeindexEditField.Enable = 'off';
            app.modeindexEditField.Tooltip = {'sim.midx'};
            app.modeindexEditField.Value = '1';
            app.MM_folderButton = uibutton(app.MultimodeTab, 'push');
            app.MM_folderButton.ButtonPushedFcn = createCallbackFcn(app, @MM_folderButtonPushed, true);
            app.MM_folderButton.Enable = 'off';
            app.MM_folderButton.Tooltip = {'fiber.MM_folder'};
            app.MM_folderButton.Text = 'MM_folder';
            app.BetasfilenameEditFieldLabel = uilabel(app.MultimodeTab);
            app.BetasfilenameEditFieldLabel.Enable = 'off';
            app.BetasfilenameEditFieldLabel.Tooltip = {''};
            app.BetasfilenameEditFieldLabel.Text = 'Betas filename';
            app.BetasfilenameEditField = uieditfield(app.MultimodeTab, 'text');
            app.BetasfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @BetasfilenameEditFieldValueChanged, true);
            app.BetasfilenameEditField.Enable = 'off';
            app.BetasfilenameEditField.Tooltip = {'fiber.betas_filename'};
            app.BetasfilenameEditField.Value = 'betas.mat';
            app.StensorsfilenameEditFieldLabel = uilabel(app.MultimodeTab);
            app.StensorsfilenameEditFieldLabel.Enable = 'off';
            app.StensorsfilenameEditFieldLabel.Text = 'S-tensors filename';
            app.StensorsfilenameEditField = uieditfield(app.MultimodeTab, 'text');
            app.StensorsfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @StensorsfilenameEditFieldValueChanged, true);
            app.StensorsfilenameEditField.Enable = 'off';
            app.StensorsfilenameEditField.Tooltip = {'fiber.S_tensors_filename'};
            app.StensorsfilenameEditField.Value = 'S_tensors_6modes.mat';
            app.ShowprogressbarButton = uibutton(app.UIFigure, 'state');
            app.ShowprogressbarButton.ValueChangedFcn = createCallbackFcn(app, @ShowprogressbarButtonValueChanged, true);
            app.ShowprogressbarButton.Tooltip = {'sim.progress_bar'};
            app.ShowprogressbarButton.Text = 'Show progress bar';
            app.ShowprogressbarButton.Value = true;
            app.TimewindowBetasButtonGroup = uibuttongroup(app.UIFigure);
            app.TimewindowBetasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimewindowBetasButtonGroupSelectionChanged, true);
            app.TimewindowBetasButtonGroup.Tooltip = {'sim.betas'; 'This defaults to the beta0 and beta1 of the first mode.'};
            app.TimewindowBetasButtonGroup.Title = 'Time-window Betas';
            app.Beta0Button_2 = uitogglebutton(app.TimewindowBetasButtonGroup);
            app.Beta0Button_2.Text = 'Beta0';
            app.Beta0Button_2.Value = true;
            app.Beta1Button_2 = uitogglebutton(app.TimewindowBetasButtonGroup);
            app.Beta1Button_2.Text = 'Beta1';
            app.mLabel_2 = uilabel(app.TimewindowBetasButtonGroup);
            app.mLabel_2.Text = '1/m';
            app.ValueEditFieldLabel = uilabel(app.TimewindowBetasButtonGroup);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.TimewindowBetasButtonGroup, 'numeric');
            app.ValueEditField.ValueChangedFcn = createCallbackFcn(app, @ValueEditFieldValueChanged, true);
            app.RmcmodelDropDownLabel = uilabel(app.UIFigure);
            app.RmcmodelDropDownLabel.Text = 'Rmc model';
            app.RmcmodelDropDown = uidropdown(app.UIFigure);
            app.RmcmodelDropDown.Items = {'No random mode coupling', 'unitary matrix R', 'iQA addition', 'Manakov'};
            app.RmcmodelDropDown.ItemsData = [0 1 2 3];
            app.RmcmodelDropDown.Value = 0;
            app.ClosethisforexportingtheparametersLabel = uilabel(app.UIFigure);
            app.ClosethisforexportingtheparametersLabel.Text = 'Close this for exporting the parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = load_GMMNLSE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end